classdef LogicSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        FileMenu             matlab.ui.container.Menu
        OpenMenu             matlab.ui.container.Menu
        SaveMenu             matlab.ui.container.Menu
        GridLayout           matlab.ui.container.GridLayout
        GridLayout6          matlab.ui.container.GridLayout
        GridLayout7          matlab.ui.container.GridLayout
        ReevaluateButton     matlab.ui.control.Button
        ResultLabel          matlab.ui.control.Label
        ResultTruthTable     matlab.ui.control.Table
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout5          matlab.ui.container.GridLayout
        ComponentsListBox    matlab.ui.control.ListBox
        Label                matlab.ui.control.Label
        GridLayout4          matlab.ui.container.GridLayout
        ActionDeleteButton   matlab.ui.control.Button
        ActionMoveButton     matlab.ui.control.Button
        ActionsLabel         matlab.ui.control.Label
        GridLayout3          matlab.ui.container.GridLayout
        SelectionTruthTable  matlab.ui.control.Table
        TruthTableLabel      matlab.ui.control.Label
        SelectionLabel       matlab.ui.control.Label
        ModeSelection        matlab.ui.container.ButtonGroup
        ModeSelectionPlace   matlab.ui.control.RadioButton
        ModeSelectionSelect  matlab.ui.control.RadioButton
        CircuitPlot          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Instance of the CircuitManager class
        circuit = CircuitManager;

        % the start of a link currently being drawn
        % this has to be stored here as, unlike all other components, links
        % require two clicks
        link_start = [-1; -1];

        % list of inputs and outputs (referenced using their component ids)
        inputs = [];
        outputs = [];
    end
    
    methods (Access = private)
        
        function reset_link_start(app)
            %RESET_LINK_START Resets the start point of the current link
            %and redraws the circuit to remove any link start marks
            app.link_start = [-1; -1];
            hold(app.CircuitPlot, "off");
            app.circuit.draw(app.CircuitPlot);
        end
        
        function add_link(app, position_clicked)
            %ADD_LINK: Extraction of the behaviour for adding links to the
            %circuit using the GUI to aid code readability

            % TODO: find a fix for not being able to click on a
            % prexisting line

            if app.link_start == [-1; -1]
                app.link_start = position_clicked;
                plot(app.CircuitPlot, position_clicked(1), position_clicked(2), "r*");
                hold(app.CircuitPlot, "on");
                app.circuit.draw(app.CircuitPlot);
            else
                % get the components and pins from the
                % positions
                [component_1, pin_1, is_input_1] = app.circuit.get_component_and_pin_from_position(app.link_start);
                [component_2, pin_2, is_input_2] = app.circuit.get_component_and_pin_from_position(position_clicked);
                            
                % check the link actually connects two pins
                if component_1 == 0 || component_2 == 0
                     uialert(app.UIFigure, "Links must go between two pins; they cannot be open on either end", "Problem creating link");
                     % reset link start
                     app.reset_link_start();
                     return;
                end

                % check the user isn't trying to connect two
                % inputs or two outputs together
                if is_input_1 == is_input_2
                    uialert(app.UIFigure, "Links cannot go between two output pins or two input pins", "Problem creating link");
                    app.reset_link_start();
                    return;
                end

                % add the link to the circuit
                if is_input_1 == true
                    app.circuit.add_link(component_2, pin_2, component_1, pin_1);
                else
                    app.circuit.add_link(component_1, pin_1, component_2, pin_2);
                end
                
                app.reset_link_start();
            end
        end
        
        function input = generate_all_input_possibilities(app, num_inputs)
            %GENERATE_ALL_INPUT_POSSIBILITIES Recursively generates the
            %complete input space for a binary input system
            %e.g. generate_all_input_possibilities(2) would return
            %⎡0 0⎤ 
            %⎢0 1⎥
            %⎢1 0⎥
            %⎣1 1⎦
            if num_inputs == 1
                input = [
                    0;
                    1;
                ];
                return;
            end
            input_one_layer_down = app.generate_all_input_possibilities(num_inputs - 1);
            input = [
                zeros(2^(num_inputs-1), 1), input_one_layer_down;
                ones(2^(num_inputs-1) , 1),  input_one_layer_down;
            ];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.CircuitPlot, [0 100 -40 40]);
            axis(app.CircuitPlot, "equal");
            app.CircuitPlot.Interactions = [];
        end

        % Button down function: CircuitPlot
        function CircuitPlotButtonDown(app, event)
            if app.ModeSelection.SelectedObject.Text == "Place"
                if event.Button == 1    % left click
                    % snap components to the grid for usability (nearest
                    % even ordinate)
                    position = round(event.IntersectionPoint(1:2)' / 2) * 2;
                    
                    % add the correct component to the circuit
                    if app.ComponentsListBox.Value == "Link"
                        app.add_link(position);
                        return;
                    end
                    % the user no longer wants a link, but would be
                    % confused if the link starting point they just
                    % selected were used for a link in the future, so
                    % it should be cleared
                    if app.link_start ~= [-1, -1]
                        app.reset_link_start();
                    end
                    if app.ComponentsListBox.Value == "Input"
                        id = app.circuit.add_component(Input(position, sprintf("in%d", length(app.inputs) + 1)));
                        app.inputs = [app.inputs, id];
                    elseif app.ComponentsListBox.Value == "Output"
                        id = app.circuit.add_component(Output(position, sprintf("out%d", length(app.outputs) + 1)));
                        app.outputs = [app.outputs, id];
                    elseif app.ComponentsListBox.Value == "NOT gate"
                        app.circuit.add_component(NotGate(position));
                    elseif app.ComponentsListBox.Value == "AND gate"
                        app.circuit.add_component(AndGate(position));
                    elseif app.ComponentsListBox.Value == "OR gate"
                        app.circuit.add_component(OrGate(position));
                    elseif app.ComponentsListBox.Value == "XOR gate"
                        app.circuit.add_component(XorGate(position));
                    elseif app.ComponentsListBox.Value == "NAND gate"
                        app.circuit.add_component(NandGate(position));
                    elseif app.ComponentsListBox.Value == "NOR gate"
                        app.circuit.add_component(NorGate(position));
                    elseif app.ComponentsListBox.Value == "XNOR gate"
                        app.circuit.add_component(XnorGate(position));
                    end
                    
                    % re-draw the circuit with the new component
                    app.circuit.draw(app.CircuitPlot);
                end
            end
        end

        % Button pushed function: ReevaluateButton
        function ReevaluateButtonPushed(app, event)
            % set up the result truth table headings
            input_headings = strings(1, length(app.inputs));
            for input = enumerate(app.inputs)
                input_headings(input.index) = app.circuit.components{input.value}.label;
            end
            output_headings = strings(1, length(app.outputs));
            for output = enumerate(app.outputs)
                output_headings(output.index) = app.circuit.components{output.value}.label;
            end
            app.ResultTruthTable.ColumnName = [input_headings, output_headings];

            % set up the truth table
            truth_table = zeros(2^length(app.inputs), length(app.inputs) + length(app.outputs));

            % go through each possible combination of inputs, and evaluate
            % the circuit for it
            input_combinations = app.generate_all_input_possibilities(length(app.inputs));
            for row = 1:size(input_combinations, 1)
                % set all the inputs to the requisite values
                current_combination = input_combinations(row, :);
                for input = 1:length(app.inputs)
                    app.circuit.components{app.inputs(input)}.input = current_combination(input);
                end
                
                % evaluate the circuit
                % updating once for each of the components means that at
                % the end, even in the worst case, the new state will be
                % the final one (for steady-state circuits)
                for i = 1:length(app.circuit.components)
                    app.circuit.update()
                end

                current_output = zeros(1, length(app.outputs));
                for output = 1:length(app.outputs)
                    current_output(output) = app.circuit.components{app.outputs(output)}.output;
                end
                truth_table(row, :) = [current_combination, current_output];
            end

            % put the truth table data into the displayed truth table
            app.ResultTruthTable.Data = truth_table;
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            app.circuit.serialise("circuits/my_circuit.lsim");
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % load the circuit from the file
            app.circuit.deserialise("circuits/my_circuit.lsim");

            % reset the lists of inputs and outputs
            app.inputs = [];
            app.outputs = [];
            for index = 1:length(app.circuit.components)
                type = app.circuit.components{index}.get_type_printable();
                if type == "input"
                    app.inputs = [app.inputs, index];
                elseif type == "output"
                    app.outputs = [app.outputs, index];
                end
            end

            % draw the newly loaded circuit
            app.circuit.draw(app.CircuitPlot);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CircuitPlot = uiaxes(app.GridLayout);
            app.CircuitPlot.XLim = [0 50];
            app.CircuitPlot.YLim = [-20 20];
            app.CircuitPlot.ButtonDownFcn = createCallbackFcn(app, @CircuitPlotButtonDown, true);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ModeSelection = uibuttongroup(app.GridLayout2);
            app.ModeSelection.Title = 'Mode';
            app.ModeSelectionSelect = uiradiobutton(app.ModeSelection);
            app.ModeSelectionSelect.Text = 'Select';
            app.ModeSelectionSelect.Value = true;
            app.ModeSelectionPlace = uiradiobutton(app.ModeSelection);
            app.ModeSelectionPlace.Text = 'Place';
            app.SelectionLabel = uilabel(app.GridLayout2);
            app.SelectionLabel.Text = 'Selection';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.TruthTableLabel = uilabel(app.GridLayout3);
            app.TruthTableLabel.Text = 'Truth Table';
            app.SelectionTruthTable = uitable(app.GridLayout3);
            app.SelectionTruthTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SelectionTruthTable.RowName = {};
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.ActionsLabel = uilabel(app.GridLayout4);
            app.ActionsLabel.Text = 'Actions';
            app.ActionMoveButton = uibutton(app.GridLayout4, 'push');
            app.ActionMoveButton.Text = 'Move';
            app.ActionDeleteButton = uibutton(app.GridLayout4, 'push');
            app.ActionDeleteButton.Text = 'Delete';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = 'Components';
            app.ComponentsListBox = uilistbox(app.GridLayout5);
            app.ComponentsListBox.Items = {'Link', 'Input', 'Output', 'NOT gate', 'AND gate', 'OR gate', 'XOR gate', 'NAND gate', 'NOR gate', 'XNOR gate'};
            app.ComponentsListBox.Value = 'Link';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.ResultTruthTable = uitable(app.GridLayout6);
            app.ResultTruthTable.ColumnName = '';
            app.ResultTruthTable.RowName = {};
            app.ResultTruthTable.Multiselect = 'off';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.ResultLabel = uilabel(app.GridLayout7);
            app.ResultLabel.Text = 'Result';
            app.ReevaluateButton = uibutton(app.GridLayout7, 'push');
            app.ReevaluateButton.ButtonPushedFcn = createCallbackFcn(app, @ReevaluateButtonPushed, true);
            app.ReevaluateButton.Text = 'Reevaluate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LogicSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end